classdef PDCW < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PreresultButton            matlab.ui.control.Button
        ProcessedaudioPanel        matlab.ui.container.Panel
        OriginalaudioPanel         matlab.ui.container.Panel
        ControlPanel               matlab.ui.container.Panel
        PlaynewaudioButton         matlab.ui.control.Button
        PlayoriginalaudioButton    matlab.ui.control.Button
        LoadaudioButton            matlab.ui.control.Button
        LanuchButton               matlab.ui.control.Button
        FramenumberEditField       matlab.ui.control.NumericEditField
        FramenumberEditFieldLabel  matlab.ui.control.Label
        EtaEditField               matlab.ui.control.NumericEditField
        EtaEditFieldLabel          matlab.ui.control.Label
        TauEditField               matlab.ui.control.NumericEditField
        TauEditFieldLabel          matlab.ui.control.Label
        OpenButton                 matlab.ui.control.Button
        USARTDropDown              matlab.ui.control.DropDown
        USARTDropDownLabel         matlab.ui.control.Label
        PRINTTextArea              matlab.ui.control.TextArea
        PRINTTextAreaLabel         matlab.ui.control.Label
    end


    properties (Access = public)
        textCurrent  % 文本区域

        s            % 串口对象
        isOpenSerial % 串口是否打开 flag

        Fs           % 音频采样频率 -> Hz
        x_Speech     % 音频原始数据 -> 双通道
        x_Left       % 音频原始数据 -> 左声道
        x_Right      % 音频原始数据 -> 右声道

        dT
        FFT_SIZE
        SpeechLen    % 完整信号的采样点数
        FrameLen     % 截断信号的采样点数
        FramePeriod  % OLA 周期
        FrameNums    % 原始音频的总共帧数

        X_Tilde_ti   % Process result
    end

    methods (Access = public)

        function toTextArea(app,str)
            app.textCurrent = horzcat(app.textCurrent,sprintf('%s\n',str));
            app.PRINTTextArea.Value = app.textCurrent;
            scroll(app.PRINTTextArea,"bottom");
        end
        
        function send_Head_info(app,Tau,Eta,SegmentNum)
            single2hex = @(x)num2hex(single(x));
            startHead = 'AAAB';
            endHead = 'zzzy';
            % 1st -> Tau
            sendMsg = [startHead single2hex(Tau) endHead];
            write(app.s,sendMsg,"uint8");
            toTextArea(app,"PC -> DSP: Tau had sent.");
            endHead = 'zzyz';

            % 2nd -> Eta
            sendMsg = [startHead single2hex(Eta) endHead];
            write(app.s,sendMsg,"uint8");
            toTextArea(app,"PC -> DSP: Eta had sent.");
            endHead = 'zyzz';

            % 3rd -> segmentNum
            sendMsg = [startHead single2hex(SegmentNum) endHead];
            write(app.s,sendMsg,"uint8");
            toTextArea(app,"PC -> DSP: Frame number had sent.");
            pause(100/1000);
        end
        
        function send_Filter_amplitude(app)
            single2hex = @(x)num2hex(single(x));
            FILTER_NUM =  5;
            H = ComputeFilterResponse(FILTER_NUM,app.FFT_SIZE);
            toTextArea(app,"PC -> Filter amplitude had generated.");
            startHead = 'AABA';
            for j=1:FILTER_NUM
                for i=1:(app.FFT_SIZE / 2)
                    h = H(i,j);
                    % 发送幅值
                    endHead = 'zzzy';
                    sendMsg = [startHead single2hex(abs(h)) endHead];
                    write(app.s,sendMsg,"uint8");
                end
                str = sprintf("PC -> DSP: %d / %d Filter amplitude had sent.",j,FILTER_NUM);
                toTextArea(app,str);
            end
        end
        
        function send_Audio_data(app,currentFrameNums)
            single2hex = @(x)num2hex(single(x));
            app.X_Tilde_ti = zeros(app.FrameNums,app.FFT_SIZE);
            startHead = 'ABAA';
            index = 0;
            for i=0:app.FramePeriod:(app.SpeechLen - 1 - app.FrameLen)
                index = index + 1;
                x_LeftFrame = app.x_Left(i + 1:i + app.FrameLen);
                x_RightFrame = app.x_Right(i + 1:i + app.FrameLen);
                tic;
                for j=1:app.FrameLen
                    endHead = 'zzzy';
                    sendMsg = [startHead single2hex(x_LeftFrame(j)) endHead];
                    write(app.s,sendMsg,"uint8");
                    endHead = 'zzzz';
                    sendMsg = [startHead single2hex(x_RightFrame(j)) endHead];
                    write(app.s,sendMsg,"uint8");
                end
                str = sprintf("PC -> DSP: %d / %d Frame had sent.",index,currentFrameNums);
                toTextArea(app,str);
                
                numReadAvai = app.s.NumBytesAvailable;
                while numReadAvai < (app.FFT_SIZE * 8)
                    numReadAvai = app.s.NumBytesAvailable;
                end

                recv_data = read(app.s,numReadAvai,"uint8");
                recv_data_hex = compose("%02X",recv_data);
                recv_data_hex = char(join(recv_data_hex,''));
                recv_data_hex = reshape(recv_data_hex,[8 2*app.FFT_SIZE]);
                recv_data_hex = recv_data_hex';
                recv_data_float = typecast(uint32(hex2dec(recv_data_hex)),'single');
                recv_data_float = recv_data_float';
                app.X_Tilde_ti(index,:) = complex(recv_data_float(1:2:end),recv_data_float(2:2:end));
                t = toc;
                str = sprintf("DSP -> PC: %d / %d Frame had received, time cost: %.3f.",index,currentFrameNums,t);
                toTextArea(app,str);
                % 实时更新绘图
                updatePDCW(app,0);
               
                pause(1/1000);
                if(index == currentFrameNums)
                    break;
                end
            end
            
        end
        
        function updatePDCW(app,flag)
            y = zeros(1,app.SpeechLen);

            % Resynthesis using OLA
            index = 0;
            for i=0:app.FramePeriod:(app.SpeechLen - 1 - app.FrameLen)
                index = index + 1;
                x_Tilde = ifft(app.X_Tilde_ti(index,:));
                x_Buffer = real(x_Tilde);
                y(i+1:i+app.FrameLen) = y(i+1:i+app.FrameLen) + x_Buffer(1:app.FrameLen);
            end

            % Removing the first and the last frames.. there were not overlapped
            y(app.SpeechLen - app.FrameLen:app.SpeechLen) = [];
            y(1:app.FrameLen) = [];
            y = y / max(abs(y(:))) / 1.1;

            % 处理NaN
            y(isnan(y)) = 0;

            % 绘制处理音频的语谱图
            % 清除上一个图像

            delete(findobj(app.ProcessedaudioPanel, 'Type', 'axes'));
            fig = figure;
            spectrogram(y,app.FFT_SIZE,0,app.FFT_SIZE,app.Fs,'yaxis');
            h = colorbar;
            h.Label.String = 'Power/Frequency(dB/Hz)';
            h = gca;
            h.Parent = app.ProcessedaudioPanel;
            delete(fig)
            % Play
            if(flag)
                sound(y,app.Fs);
            else

            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % 界面初始化完毕
            app.isOpenSerial = 0;
            % Disable Button
            app.LanuchButton.Enable = "off";
            app.LoadaudioButton.Enable = "off";
            app.PlayoriginalaudioButton.Enable = "off";
            app.PlaynewaudioButton.Enable = "off";
            % 获取串口列表
            app.USARTDropDown.Items = serialportlist("available");
            app.textCurrent{1} = sprintf('%s\n','PC -> Serialport was initialized.');
            app.PRINTTextArea.Value = app.textCurrent;
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            % Open / Close Serialport
            valueCOM = app.USARTDropDown.Value;
            if(app.isOpenSerial == 0)
                % Open
                app.s = serialport(valueCOM,9600,'Timeout',5);
                % 清空缓冲区
                flush(app.s);
                % 更新 TextArea 信息
                toTextArea(app,"PC -> Serialport was opened.");
                % 更新 Button 信息
                app.LoadaudioButton.Enable = "on";
                app.OpenButton.Text = "Close";
                app.isOpenSerial = 1;
            else
                % Close
                delete(app.s);
                % 更新 TextArea 信息
                toTextArea(app,"PC -> Serialport was closed.");
                % 更新 Button 信息
                app.LoadaudioButton.Enable = "off";
                app.PlayoriginalaudioButton.Enable = "off";
                app.PlaynewaudioButton.Enable = "off";
                app.LanuchButton.Enable = "off";
                app.OpenButton.Text = "Open";
                app.isOpenSerial = 0;
            end
        end

        % Button pushed function: LoadaudioButton
        function LoadaudioButtonPushed(app, event)
            % 加载音频文件
            [file,path] = uigetfile('*.wav');
            if isequal(file,0)
                disp('User selected Cancel.');
            else
                [app.x_Speech,app.Fs] = audioread(fullfile(path,file));
                toTextArea(app,'PC -> Orignal audio data was loaded.');
            end
            app.x_Left = app.x_Speech(:,1)';
            app.x_Right = app.x_Speech(:,2)';
            app.dT = 75e-3 / 32;
            app.SpeechLen = length(app.x_Left);                                     % Speech -> 完整信号的采样点数
            app.FrameLen = round(app.Fs * app.dT);                                  % Speech -> 截断信号的采样点数
            app.FramePeriod = round(app.Fs * app.dT / 2);                           % Speech -> OLA 周期
            app.FFT_SIZE = 2^ceil(log(app.FrameLen) / log(2));
            % 计算帧数
            app.FrameNums = floor((app.SpeechLen - app.FrameLen) / app.FramePeriod) + 1;
            app.FramenumberEditField.Value = app.FrameNums;
            % 预设阈值
            app.TauEditField.Value = 0.2;
            % 预设抑制系数
            app.EtaEditField.Value = 0.01;
            % 更新 Button 信息
            app.PlayoriginalaudioButton.Enable = "on";
            app.LanuchButton.Enable = "on";
        end

        % Button pushed function: PlayoriginalaudioButton
        function PlayoriginalaudioButtonPushed(app, event)
            % 播放原始音频
            y = 0.5*(app.x_Left + app.x_Right);
            % 更新 textArea 信息
            toTextArea(app,"PC -> Orignal audio was played, and its spectrogram result was listed on the left figure.")
            % 绘制原始音频的语谱图
            fig = figure;
            spectrogram(y,app.FFT_SIZE,0,app.FFT_SIZE,app.Fs,'yaxis');
            h = colorbar;
            h.Label.String = 'Power/Frequency(dB/Hz)';
            h = gca;
            h.Parent = app.OriginalaudioPanel;
            delete(fig)
            % Play
            sound(y,app.Fs);
        end

        % Button pushed function: LanuchButton
        function LanuchButtonPushed(app, event)
            % 获取当前输入参数
            currentTau = app.TauEditField.Value;
            currentEta = app.EtaEditField.Value;
            currentFrameNums = app.FramenumberEditField.Value;

            % 1. Head info
            toTextArea(app,"----------Send Head info start----------");
            send_Head_info(app,currentTau,currentEta,currentFrameNums);
            toTextArea(app,"----------Send Head info done-----------");
            
            % 2. Filter amplitude
            toTextArea(app,"----------Send Filter amplitude start----------");
            send_Filter_amplitude(app)
            toTextArea(app,"----------Send Filter amplitude done-----------");

            % 3. Audio data
            toTextArea(app,"----------PDCW start----------");
            send_Audio_data(app,currentFrameNums);
            toTextArea(app,"----------PDCW done-----------");
            toTextArea(app,"PC -> Processed audio data had generated, you could click the Play new audio button now.");
            app.PlaynewaudioButton.Enable = "on";
        end

        % Button pushed function: PlaynewaudioButton
        function PlaynewaudioButtonPushed(app, event)
            updatePDCW(app,1);
        end

        % Button pushed function: PreresultButton
        function PreresultButtonPushed(app, event)
            % 加载已处理好的结果
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
                disp('User selected Cancel.');
            else
                temp = load(fullfile(path,file));
                app.X_Tilde_ti = temp.X_Tilde_ti;
                updatePDCW(app,1);
                toTextArea(app,'PC -> Pre-process audio data was loaded and played.');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ControlPanel = uipanel(app.UIFigure);
            app.ControlPanel.Title = 'Control';
            app.PRINTTextAreaLabel = uilabel(app.ControlPanel);
            app.PRINTTextAreaLabel.Text = 'PRINT';
            app.PRINTTextArea = uitextarea(app.ControlPanel);
            app.USARTDropDownLabel = uilabel(app.ControlPanel);
            app.USARTDropDownLabel.Text = 'USART';
            app.USARTDropDown = uidropdown(app.ControlPanel);
            app.USARTDropDown.Items = {'COM3', 'COM6', 'COM9'};
            app.USARTDropDown.Value = 'COM3';
            app.OpenButton = uibutton(app.ControlPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.TauEditFieldLabel = uilabel(app.ControlPanel);
            app.TauEditFieldLabel.Text = 'Tau';
            app.TauEditField = uieditfield(app.ControlPanel, 'numeric');
            app.EtaEditFieldLabel = uilabel(app.ControlPanel);
            app.EtaEditFieldLabel.Text = 'Eta';
            app.EtaEditField = uieditfield(app.ControlPanel, 'numeric');
            app.FramenumberEditFieldLabel = uilabel(app.ControlPanel);
            app.FramenumberEditFieldLabel.Text = 'Frame number';
            app.FramenumberEditField = uieditfield(app.ControlPanel, 'numeric');
            app.LanuchButton = uibutton(app.ControlPanel, 'push');
            app.LanuchButton.ButtonPushedFcn = createCallbackFcn(app, @LanuchButtonPushed, true);
            app.LanuchButton.Text = 'Lanuch';
            app.LoadaudioButton = uibutton(app.ControlPanel, 'push');
            app.LoadaudioButton.ButtonPushedFcn = createCallbackFcn(app, @LoadaudioButtonPushed, true);
            app.LoadaudioButton.Text = 'Load audio';
            app.PlayoriginalaudioButton = uibutton(app.ControlPanel, 'push');
            app.PlayoriginalaudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlayoriginalaudioButtonPushed, true);
            app.PlayoriginalaudioButton.Text = 'Play original audio';
            app.PlaynewaudioButton = uibutton(app.ControlPanel, 'push');
            app.PlaynewaudioButton.ButtonPushedFcn = createCallbackFcn(app, @PlaynewaudioButtonPushed, true);
            app.PlaynewaudioButton.Text = 'Play new audio';
            app.OriginalaudioPanel = uipanel(app.UIFigure);
            app.OriginalaudioPanel.Title = 'Original audio';
            app.ProcessedaudioPanel = uipanel(app.UIFigure);
            app.ProcessedaudioPanel.Title = 'Processed audio';
            app.PreresultButton = uibutton(app.UIFigure, 'push');
            app.PreresultButton.ButtonPushedFcn = createCallbackFcn(app, @PreresultButtonPushed, true);
            app.PreresultButton.Text = 'Pre-result';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PDCW

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end